classdef VollImBilde < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        kpTiltEditField              matlab.ui.control.NumericEditField
        kpTiltEditFieldLabel         matlab.ui.control.Label
        kiTiltEditField              matlab.ui.control.NumericEditField
        kiTiltEditFieldLabel         matlab.ui.control.Label
        kpPanEditField               matlab.ui.control.NumericEditField
        kpPanEditFieldLabel          matlab.ui.control.Label
        kiPanEditField               matlab.ui.control.NumericEditField
        kiPanEditFieldLabel          matlab.ui.control.Label
        BildpositionLabel            matlab.ui.control.Label
        MountingSwitch               matlab.ui.control.ToggleSwitch
        MountingSwitchLabel          matlab.ui.control.Label
        TiltPosSlider                matlab.ui.control.Slider
        TiltPosSliderLabel           matlab.ui.control.Label
        PanPosSlider                 matlab.ui.control.Slider
        PanPosSliderLabel            matlab.ui.control.Label
        StateBildverarbeitung        matlab.ui.control.Lamp
        BildverarbeitungOnOffButton  matlab.ui.control.StateButton
        ControllerState              matlab.ui.control.Lamp
        WebcamwhlenDropDown          matlab.ui.control.DropDown
        WebcamwhlenDropDownLabel     matlab.ui.control.Label
        mitte                        matlab.ui.control.Button
        ControllerButton             matlab.ui.control.StateButton
        h1VollimBildeV01h1Label      matlab.ui.control.Label
        untenrechts                  matlab.ui.control.Button
        untenlinks                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Cam % WebCamobjekt
        Ardu %Arduinoobjekt
        Pan %Servoobjekt für Panbewegung
        Tilt %Servoobjekt für Tiltbewegung
        PanStart = 0.5;
        TiltStart = 0.9;
        Detector
        SampleTime = 0.2;
        PanController %Reglerobjekt
        TiltController
        Mounting % Davon hängen auch Buttons(Sollwerte) ab!
        VisionType = 'peopleACF'
        VideoPlayer;
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Arduino initialisieren
            app.Ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo'); %evtl auch noch als Dropdown?
            app.Pan = servo(app.Ardu, 'D3');
            app.Pan.writePosition(app.PanStart);
            app.Tilt = servo(app.Ardu, 'D2');
            app.Tilt.writePosition(app.TiltStart);
            %Dropdown für WebCam initalisieren
            imaqreset;
            res = imaqhwinfo('winvideo');
            app.WebcamwhlenDropDown.Items = {res.DeviceInfo.DeviceName};
            app.WebcamwhlenDropDown.ItemsData = res.DeviceIDs;
            app.WebcamwhlenDropDown.Value = 1;
            %Webcam initalisieren
            app.Cam = videoinput('winvideo', app.WebcamwhlenDropDown.Value);
            triggerconfig(app.Cam, 'manual');
            app.Cam.start; %ToDo: app.Cam.stop am Ende
            %Bilderkennung initalisieren
            app.Detector = ImagePeopleDetector(app.VisionType, 'LowPass');
            %Silder initalisieren
            app.PanPosSlider.Value = app.PanStart;
            app.TiltPosSlider.Value = app.TiltStart;
            %Regler init
            app.PanController = Controller('PI', 1.89, 0.15, 2*app.SampleTime, app.PanStart); %Reglerobjekt
            app.TiltController = Controller('PI', 2.4, 0.15, 2*app.SampleTime, app.TiltStart);
            app.kiPanEditField.Value = app.PanController.ki;
            app.kpPanEditField.Value = app.PanController.kp;
            app.kiTiltEditField.Value = app.TiltController.ki;
            app.kpTiltEditField.Value = app.TiltController.kp;

        end

        % Value changed function: WebcamwhlenDropDown
        function WebcamwhlenDropDownValueChanged(app, event)
            value = app.WebcamwhlenDropDown.Value;
            webcamNum = value;
            app.Cam = videoinput('winvideo', webcamNum);
            triggerconfig(app.Cam, 'manual');
            start(app.Cam); 
            app.VideoPlayer = vision.VideoPlayer;
        end

        % Callback function
        function WebcamwhlenDropDownOpening(app, event)
    	    %delete(app.Cam); %löscht das auch Property/überhaupt nötig?
            %imaqreset; %wichtig, dass Liste aktuell ist, aber zerstört auch alle Objekte ...
            %res = imaqhwinfo('winvideo');
            %app.WebcamwhlenDropDown.Items = {res.DeviceInfo.DeviceName};
            %app.WebcamwhlenDropDown.ItemsData = res.DeviceIDs;
        end

        % Value changed function: ControllerButton
        function ControllerButtonValueChanged(app, event)
            value = app.ControllerButton.Value;
            if value
                app.ControllerState.Color = [0.00 1.00 0.00]; %Grün
                %evtl noch Text ändern auf Button
            else
                app.ControllerState.Color = [1.00 0.00 0.00]; %Rot
            end
        end

        % Value changed function: BildverarbeitungOnOffButton
        function BildverarbeitungOnOffButtonValueChanged(app, event)
            value = app.BildverarbeitungOnOffButton.Value;
            if value
                app.StateBildverarbeitung.Color = [0.00 1.00 0.00]; %Grün
                app.VideoPlayer = vision.VideoPlayer;
            else
                app.StateBildverarbeitung.Color = [1.00 0.00 0.00]; %Rot
                release(app.VideoPlayer);
            end
            while(app.BildverarbeitungOnOffButton.Value) 
                tic;
                act_img = getsnapshot(app.Cam);
                %Bild rotieren bei Deckenmontage
                if app.MountingSwitch.Value
                else
                   act_img = imrotate(act_img,180);
                end
                %Bildverarbeitung
                [out_img,is_detected,x_Filt,y_Filt] = doPeopleDetection(app.Detector, act_img);
                %Controller Kontrollstruktur über ControllerOnOffButton
                if app.ControllerButton.Value
                    %Reglerobjekt Werte übergeben
                    uPan = app.PanController.getOutput(x_Filt);
                    uTilt = app.TiltController.getOutput(y_Filt);
                    %Servos schreiben
                    app.Pan.writePosition(uPan);
                    app.Tilt.writePosition(uTilt);
                    % Kann zur Laufzeitoptimierung raus
                    app.PanPosSlider.Value = uPan;
                    app.TiltPosSlider.Value = uTilt;
                else
                    %Werte aus Schiebereglern schreiben
                    app.Pan.writePosition(app.PanPosSlider.Value);
                    app.Tilt.writePosition(app.TiltPosSlider.Value);
                end
                %Bild akutallisieren
                app.VideoPlayer(out_img); 
                %Pause um interrupts zu bearbeiten und feste Abtaktrate zu
                %geben
                %evtl überwachen und flexibel machen, dass genug Zeit für
                %interrupts ist
                looptime = toc
                if looptime < app.SampleTime
                    pause(app.SampleTime-looptime);
                end
            end
        end

        % Button pushed function: mitte
        function mitteButtonPushed(app, event)
            %ToDo: Mounting hinzufügen!
            x = 0.5;
            y = 0.5;
            updateDesOutput(app.PanController, x);
            updateDesOutput(app.PanController, y);
        end

        % Value changed function: MountingSwitch
        function MountingSwitchValueChanged(app, event)
            value = app.MountingSwitch.Value;
            app.Mounting = value;
        end

        % Button pushed function: untenlinks
        function untenlinksButtonPushed(app, event)
            x = 0.3;
            y = 0.4;
            if app.Mounting
                x = 1-x;
                y = 1-y;
            end
            updateDesOutput(app.PanController, x);
            updateDesOutput(app.TiltController, y);
        end

        % Button pushed function: untenrechts
        function untenrechtsButtonPushed(app, event)
            x = 0.7;
            y = 0.4;
            if app.Mounting
                x = 1-x;
                y = 1-y;
            end
            updateDesOutput(app.PanController, x);
            updateDesOutput(app.TiltController, y);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            release(app.VideoPlayer);
            delete(app)
            imaqreset;
        end

        % Value changed function: kiPanEditField
        function kiPanEditFieldValueChanged(app, event)
            value = app.kiPanEditField.Value;
            app.PanController.setParams(value,'ki');
        end

        % Value changed function: kpPanEditField
        function kpPanEditFieldValueChanged(app, event)
            value = app.kpPanEditField.Value;
            app.PanController.setParams(value,'kp');
        end

        % Value changed function: kiTiltEditField
        function kiTiltEditFieldValueChanged(app, event)
            value = app.kiTiltEditField.Value;
            app.TiltController.ki = value;
        end

        % Value changed function: kpTiltEditField
        function kpTiltEditFieldValueChanged(app, event)
            value = app.kpTiltEditField.Value;
            app.TiltController.kp = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.untenlinks = uibutton(app.UIFigure, 'push');
            app.untenlinks.ButtonPushedFcn = createCallbackFcn(app, @untenlinksButtonPushed, true);
            app.untenlinks.Text = 'unten links';
            app.untenrechts = uibutton(app.UIFigure, 'push');
            app.untenrechts.ButtonPushedFcn = createCallbackFcn(app, @untenrechtsButtonPushed, true);
            app.untenrechts.Text = 'unten rechts';
            app.h1VollimBildeV01h1Label = uilabel(app.UIFigure);
            app.h1VollimBildeV01h1Label.Interpreter = 'html';
            app.h1VollimBildeV01h1Label.Text = '<h1>Voll im Bilde V0.1.<h1>';
            app.ControllerButton = uibutton(app.UIFigure, 'state');
            app.ControllerButton.ValueChangedFcn = createCallbackFcn(app, @ControllerButtonValueChanged, true);
            app.ControllerButton.Text = 'Folgen';
            app.mitte = uibutton(app.UIFigure, 'push');
            app.mitte.ButtonPushedFcn = createCallbackFcn(app, @mitteButtonPushed, true);
            app.mitte.Text = 'mitte';
            app.WebcamwhlenDropDownLabel = uilabel(app.UIFigure);
            app.WebcamwhlenDropDownLabel.Text = 'Webcam wählen ..';
            app.WebcamwhlenDropDown = uidropdown(app.UIFigure);
            app.WebcamwhlenDropDown.Items = {'...'};
            app.WebcamwhlenDropDown.ValueChangedFcn = createCallbackFcn(app, @WebcamwhlenDropDownValueChanged, true);
            app.WebcamwhlenDropDown.Value = '...';
            app.ControllerState = uilamp(app.UIFigure);
            app.BildverarbeitungOnOffButton = uibutton(app.UIFigure, 'state');
            app.BildverarbeitungOnOffButton.ValueChangedFcn = createCallbackFcn(app, @BildverarbeitungOnOffButtonValueChanged, true);
            app.BildverarbeitungOnOffButton.Text = {'Bildverarbeitung'; 'On/Off'};
            app.StateBildverarbeitung = uilamp(app.UIFigure);
            app.PanPosSliderLabel = uilabel(app.UIFigure);
            app.PanPosSliderLabel.Text = 'Pan Pos.';
            app.PanPosSlider = uislider(app.UIFigure);
            app.PanPosSlider.Limits = [0 1];
            app.TiltPosSliderLabel = uilabel(app.UIFigure);
            app.TiltPosSliderLabel.Text = 'Tilt Pos.';
            app.TiltPosSlider = uislider(app.UIFigure);
            app.TiltPosSlider.Limits = [0 1];
            app.MountingSwitchLabel = uilabel(app.UIFigure);
            app.MountingSwitchLabel.Text = 'Mounting';
            app.MountingSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MountingSwitch.Items = {'Stativ', 'Decke'};
            app.MountingSwitch.ItemsData = [1 0];
            app.MountingSwitch.ValueChangedFcn = createCallbackFcn(app, @MountingSwitchValueChanged, true);
            app.MountingSwitch.Value = 1;
            app.BildpositionLabel = uilabel(app.UIFigure);
            app.BildpositionLabel.Text = 'Bildposition';
            app.kiPanEditFieldLabel = uilabel(app.UIFigure);
            app.kiPanEditFieldLabel.Text = 'ki Pan';
            app.kiPanEditField = uieditfield(app.UIFigure, 'numeric');
            app.kiPanEditField.ValueChangedFcn = createCallbackFcn(app, @kiPanEditFieldValueChanged, true);
            app.kpPanEditFieldLabel = uilabel(app.UIFigure);
            app.kpPanEditFieldLabel.Text = 'kp Pan';
            app.kpPanEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpPanEditField.ValueChangedFcn = createCallbackFcn(app, @kpPanEditFieldValueChanged, true);
            app.kiTiltEditFieldLabel = uilabel(app.UIFigure);
            app.kiTiltEditFieldLabel.Text = 'ki Tilt';
            app.kiTiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.kiTiltEditField.ValueChangedFcn = createCallbackFcn(app, @kiTiltEditFieldValueChanged, true);
            app.kpTiltEditFieldLabel = uilabel(app.UIFigure);
            app.kpTiltEditFieldLabel.Text = 'kp Tilt';
            app.kpTiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpTiltEditField.ValueChangedFcn = createCallbackFcn(app, @kpTiltEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VollImBilde

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end