classdef ServoPositionierung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        yactEditField       matlab.ui.control.NumericEditField
        yactEditFieldLabel  matlab.ui.control.Label
        xactEditField       matlab.ui.control.NumericEditField
        TiltEditField       matlab.ui.control.NumericEditField
        TiltEditFieldLabel  matlab.ui.control.Label
        TiltSlider          matlab.ui.control.Slider
        TiltSliderLabel     matlab.ui.control.Label
        SpinSlider          matlab.ui.control.Slider
        SpinSliderLabel     matlab.ui.control.Label
        SpinEditField       matlab.ui.control.NumericEditField
        SpinEditFieldLabel  matlab.ui.control.Label
        StartCamButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam % Webcam
        ardu % Arduinoverbindung
        spin % Servoobjekt für Spin
        tilt %Servoobjekt
        state = 0;
        detector
        n_filter = 4;
        xact 
        yact 
        visionType = 'peopleACF';
        vidobj
    end
    
    methods (Access = private)
        
        function [image_out,person_detected,xbox_filt,ybox_filt] = ...
    doPeopleDetection(app,img, def_pos)

%   'none': Erster Eintrag wird gewählt (später wird gefiltert)
%   'mean': Mittelwert aus allen Einträgen
XY_WEIGHT = 'none';
% Gewichtung der Ausgabewerte
% 'none': Keine Filterung
% 'mean': Mittelwertfilterung
OUTPUT_FILT = 'mean';

% Konstanten für die Offsetverschiebung der detektierten Objektposition
% Bezug: links oben, normiert
% Typ CascadeDetection
SC_X_CASCADE = 0.5;
SC_Y_CASCADE = 1;
% Typ PeopleACF
SC_X_ACF = 0.5;
% Brust liegt auf etwa 75% der Körpergröße
SC_Y_ACF = 1 - 0.75;

%% Bild holen
% Referenzwerte
[img_height,img_width,~] = size(img);
% Erkennung - bboxes =
switch app.visionType
    case 'cascade'
        bbox = step(app.detector, img);
    case 'people'
        [bbox, ~] = app.detector(img);
    case 'peopleACF'
        [bbox, ~] = detect(app.detector,img);
end
% Boxen dem Bild hinzufügen - Debugging
% im2 = insertObjectAnnotation(img, 'rectangle', bbox, 'person', 'Color', 'red');

%% Filterung der Werte
% Schieberegister
app.xact(2:end) = app.xact(1:end-1);
app.yact(2:end) = app.yact(1:end-1);

if isempty(bbox)
    app.xact(1) = def_pos;
    app.yact(1) = def_pos;
    person_detected = 0;
    
else
%     bbox
    %% Offset-Verschiebung je nach Art der Bildverarbeitung
    % Zielpunkt: Brust
    % Das Positionsergebnis aus der Bilderkennung wird um einen
    % spezifischen Offset verschoben.
    switch app.visionType
        case 'cascade'
            % Hier wird eher das Gesicht erkannt -> Mitte der unteren Kante
            bbox_off(:,1) = (bbox(1,1)) + bbox(1,3) * SC_X_CASCADE;
            % Komplette Höhe hinzu
            bbox_off(:,2) = (bbox(1,2)) + bbox(1,4) * SC_Y_CASCADE;
        case {'people','peopleACF'}
            % Hier wird der komplette Körper erkannt - 75% der Körperhöhe
            bbox_off(:,1) = (bbox(1,1)) + bbox(1,3) * SC_X_ACF;
            bbox_off(:,2) = (bbox(1,2)) + bbox(1,4) * SC_Y_ACF;
    end
    
    %% Mittelwertbildung der möglichen Boxen
    % Da der Mittelpunkt der Person ausgegeben werden soll muss die Breite
    % mit beachtet werden. bbox hat folgenden Aufbau, Bezug linke obere
    % Ecke des Bilds:
    %   [x y Breite Höhe]
    switch XY_WEIGHT
        case 'none'
            % Ansatz 0 - Erster Eintrag
            x_box_mean = bbox_off(1,1);
            y_box_mean = bbox_off(1,2);
        case 'mean'
            % Ansatz 1 - Mittelwert: Bezug links oben
            x_box_mean = mean(bbox_off(:,1));
            y_box_mean = mean(bbox_off(:,2));    
    end
        % Offset auf links unten
        xbox = x_box_mean;
        ybox = img_height - y_box_mean;
        
        % Ausgabe
        app.xact(1) = xbox/img_width;
        app.yact(1) = ybox/img_width;
        
        person_detected = 1;
end

    %% Filterung der Werte
    
    % Var 1: Aktuellster Wert
    switch OUTPUT_FILT
        case 'none'
            xbox_filt = app.xact;
            ybox_filt = app.yact;
        case 'mean'
            % Var 2: Mittelwert
            xbox_filt = mean(app.xact);
            ybox_filt = mean(app.yact);
    end
    
    %% Rechteck einfügen - Bezug links oben
    x_actbox = xbox_filt - 0.05*img_height;
    y_actbox = img_height - ybox_filt - 0.05*img_height;
    br_actbox = 0.1*img_height;
    actbox = [x_actbox,y_actbox,br_actbox,br_actbox];
    image_out = insertShape(img,'Rectangle',actbox);
    image_out = insertObjectAnnotation(image_out, 'rectangle', bbox, 'person', 'Color', 'red');
        end
 end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Objekte anlegen
            imaqreset;
            res = imaqhwinfo('winvideo');
            allcams = {res.DeviceInfo.DeviceName};
            cam_idx = find(contains(allcams,'FC-85B'));
            app.vidobj = videoinput('winvideo',cam_idx);
            triggerconfig(app.vidobj, 'manual');
            start(app.vidobj);
            %app.cam = webcam(2);
            app.ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo');
            app.spin = servo(app.ardu, 'D2');
            app.tilt = servo(app.ardu, 'D3');
            %Startwerte setzen
            writePosition(app.spin, 0.5);
            app.SpinEditField.Value = 0.5;
            app.SpinSlider.Value = 0.5;
            writePosition(app.tilt, 0.35);
            app.TiltEditField.Value = 0.35;
            app.TiltSlider.Value = 0.35;
            %Bilderkennung
            VISION_TYP = 'peopleACF';
            switch VISION_TYP
                % Erkennt Gesichter/Oberkörper gut: Rückgabe ist eher das Gesicht
                case 'cascade'
                    app.detector = vision.CascadeObjectDetector(...
                        'ClassificationModel', 'UpperBody');
                case 'people'
                    % Erkennt Oberkörper
                    app.detector = vision.PeopleDetector(...
                        'ClassificationModel', 'UprightPeople_96x48');
                case 'peopleACF'
                    % ACF-Algorithmus, erkennt ganze Personen oder Oberkörper
                    app.detector = peopleDetectorACF;
            end
            app.xact = zeros(app.n_filter,1);
            app.yact = zeros(app.n_filter,1);
        end

        % Button pushed function: StartCamButton
        function StartCamButtonPushed(app, event)
%             frame = snapshot(app.cam);
%             im = image(app.UIAxes, zeros(size(frame), 'uint8'));
%             axis(app.UIAxes, 'image');
%             preview(app.cam, im);
              app.state = xor(app.state,1);
              while(app.state)
                      act_img = getsnapshot(app.vidobj);
                      [img_boxes, person_detected, x, y] = app.doPeopleDetection(act_img, 0.5);
                      %app.Image.ImageSource = img_boxes;
                      imshow(img_boxes, 'Parent', app.UIAxes); 
                      app.xactEditField.Value = x;
                      app.yactEditField.Value = y;
                      pause(0.05);
              end
        end

        % Callback function
        function InitButtonPushed(app, event)
       
        end

        % Callback function
        function DriveButtonPushed(app, event)

        end

        % Value changed function: SpinSlider
        function SpinSliderValueChanged(app, event)
            value = app.SpinSlider.Value;
            writePosition(app.spin, value);
            app.SpinEditField.Value = value;
        end

        % Value changed function: TiltSlider
        function TiltSliderValueChanged(app, event)
            value = app.TiltSlider.Value;
            writePosition(app.tilt, value);
            app.TiltEditField.Value = value;
        end

        % Value changed function: SpinEditField
        function SpinEditFieldValueChanged(app, event)
            value = app.SpinEditField.Value;
            writePosition(app.spin, value);
            app.SpinSlider.Value = value;
        end

        % Value changed function: TiltEditField
        function TiltEditFieldValueChanged(app, event)
            value = app.TiltEditField.Value;
            writePosition(app.tilt, value);
            app.TiltSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartCamButton = uibutton(app.UIFigure, 'push');
            app.StartCamButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCamButton.Text = 'Start Cam';
            app.SpinEditFieldLabel = uilabel(app.UIFigure);
            app.SpinEditFieldLabel.Text = 'Spin';
            app.SpinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpinEditField.ValueChangedFcn = createCallbackFcn(app, @SpinEditFieldValueChanged, true);
            app.SpinSliderLabel = uilabel(app.UIFigure);
            app.SpinSliderLabel.Text = 'Spin';
            app.SpinSlider = uislider(app.UIFigure);
            app.SpinSlider.Limits = [0 1];
            app.SpinSlider.ValueChangedFcn = createCallbackFcn(app, @SpinSliderValueChanged, true);
            app.TiltSliderLabel = uilabel(app.UIFigure);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.UIFigure);
            app.TiltSlider.Limits = [0 1];
            app.TiltSlider.ValueChangedFcn = createCallbackFcn(app, @TiltSliderValueChanged, true);
            app.TiltEditFieldLabel = uilabel(app.UIFigure);
            app.TiltEditFieldLabel.Text = 'Tilt';
            app.TiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiltEditField.ValueChangedFcn = createCallbackFcn(app, @TiltEditFieldValueChanged, true);
            app.xactEditField = uieditfield(app.UIFigure, 'numeric');
            app.yactEditFieldLabel = uilabel(app.UIFigure);
            app.yactEditFieldLabel.Text = 'yact';
            app.yactEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ServoPositionierung

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end